classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        EditField3               matlab.ui.control.EditField
        Image2                   matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        EditField2               matlab.ui.control.NumericEditField
        DetailLabel              matlab.ui.control.Label
        BlockSizeEditField       matlab.ui.control.NumericEditField
        BlockSizeEditFieldLabel  matlab.ui.control.Label
        EXITButton               matlab.ui.control.Button
        CLEARButton              matlab.ui.control.Button
        CONVERTHALFTONEButton    matlab.ui.control.Button
        UPLOADIMAGEButton        matlab.ui.control.Button
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

    end
    
    methods (Access = private)
       function circle(~,ax,x,y,r)
            pos = [x-0.5*r y-0.5*r r r];
            rectangle(ax,'Position',pos,'Curvature',[1 1],'FaceColor',[0 0 0]);
       end
       
       function halftone(app,X,block_size, detail)

            halftoneAxes=app.UIAxes2;
            
            if length(size(X))>2
                X = rgb2gray(X);
            end

            if(detail>block_size)
               disp('Error:Value of detail must be less than the block_size.');

            else
                X = im2double(X);
               [m n] = size(X);
               fun = @(block_struct) mean(mean(block_struct.data));
               I2 = blockproc(X,[block_size block_size],fun);
               I2 = I2- min(min(I2));

               Imax = max(max(I2));
               delta = Imax/detail;
               I2r = ceil(I2/delta);
               I2r = block_size-I2r;
               I2r = flipud(I2r);

               midpoints_y = [block_size/2:block_size:m];
               midpoints_x = [block_size/2:block_size:n];

               for i=1:length(midpoints_x)
                for j=1:length(midpoints_y)
                    app.circle(halftoneAxes,midpoints_x(i), midpoints_y(j), I2r(j,i));
                    hold(halftoneAxes,"on");
                end
               end
            axis(halftoneAxes,"equal");
           end
end
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UPLOADIMAGEButton
        function UPLOADIMAGEButtonPushed(app, event)
           global ImageFile;
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, filename];
           ImageFile = imread(fullname);
           imshow(ImageFile,'Parent',app.UIAxes);
        end

        % Button pushed function: CONVERTHALFTONEButton
        function CONVERTHALFTONEButtonPushed(app, event)
          
            cla(app.UIAxes2);
            global ImageFile;
           X=ImageFile;
           a=app.BlockSizeEditField.Value;
           b=app.EditField2.Value;
           halftone(app,X,a,b);
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            cla(app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Halftone Image')
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTickLabel = '';
            app.UPLOADIMAGEButton = uibutton(app.UIFigure, 'push');
            app.UPLOADIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOADIMAGEButtonPushed, true);
            app.UPLOADIMAGEButton.Text = 'UPLOAD IMAGE';
            app.CONVERTHALFTONEButton = uibutton(app.UIFigure, 'push');
            app.CONVERTHALFTONEButton.ButtonPushedFcn = createCallbackFcn(app, @CONVERTHALFTONEButtonPushed, true);
            app.CONVERTHALFTONEButton.Text = {'CONVERT'; 'HALFTONE'};
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = {'CLEAR'; ''};
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.BlockSizeEditFieldLabel = uilabel(app.UIFigure);
            app.BlockSizeEditFieldLabel.Text = 'Block Size';
            app.BlockSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.BlockSizeEditField.Value = 10;
            app.DetailLabel = uilabel(app.UIFigure);
            app.DetailLabel.Text = 'Detail';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.Value = 10;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Ankara_Yldrm_Beyazt_University_logo.svg.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Enable = 'off';
            app.Image2.ImageSource = 'All_rights_reserved_logo.svg.png';
            app.EditField3 = uieditfield(app.UIFigure, 'text');
            app.EditField3.Editable = 'off';
            app.EditField3.Enable = 'off';
            app.EditField3.Value = 'Copyright by Gökhan Gök&Muhammed Said Yıldız';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end